classdef Virtual_MRI_Scanner_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SwapHorizontalVerticalLinesCheckBox  matlab.ui.control.CheckBox
        MatrixWidthEditField          matlab.ui.control.NumericEditField
        MatrixWidthEditFieldLabel     matlab.ui.control.Label
        PointDensityEditField         matlab.ui.control.NumericEditField
        RadialDensityEditField        matlab.ui.control.NumericEditField
        PointDensityEditFieldLabel    matlab.ui.control.Label
        RadialDensityEditFieldLabel   matlab.ui.control.Label
        PointDensityEditField_2       matlab.ui.control.NumericEditField
        LineDensityEditField          matlab.ui.control.NumericEditField
        LineDensityEditFieldLabel     matlab.ui.control.Label
        PointDensityEditField_2Label  matlab.ui.control.Label
        AcquisitionTrajectoryButtonGroup  matlab.ui.container.ButtonGroup
        RadialButton                  matlab.ui.control.RadioButton
        CartesianButton               matlab.ui.control.RadioButton
        CenterPanel                   matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.LineDensityEditField.Visible = true;
    app.LineDensityEditFieldLabel.Visible = true;

    app.PointDensityEditField.Visible = true;
    app.PointDensityEditFieldLabel.Visible = true;

    app.MatrixWidthEditField.Visible = true;
    app.MatrixWidthEditFieldLabel.Visible = true;

    app.SwapHorizontalVerticalLinesCheckBox.Visible = true;

    app.RadialDensityEditField.Visible = false;
    app.RadialDensityEditFieldLabel.Visible = false;

    app.PointDensityEditField_2.Visible = false; 
    app.PointDensityEditField_2Label.Visible = false;
        end

        % Selection changed function: AcquisitionTrajectoryButtonGroup
        function AcquisitionTrajectoryButtonGroupSelectionChanged(app, event)
    selectedButton = app.AcquisitionTrajectoryButtonGroup.SelectedObject; % Get the selected button

    if selectedButton == app.CartesianButton
        % Show Cartesian-specific components
        app.LineDensityEditField.Visible = true;
        app.LineDensityEditFieldLabel.Visible = true;

        app.PointDensityEditField.Visible = true;
        app.PointDensityEditFieldLabel.Visible = true;

        app.MatrixWidthEditField.Visible = true;
        app.MatrixWidthEditFieldLabel.Visible = true;

        app.SwapHorizontalVerticalLinesCheckBox.Visible = true;

        % Hide Radial-specific components
        app.RadialDensityEditField.Visible = false;
        app.RadialDensityEditFieldLabel.Visible = false;

        app.PointDensityEditField_2.Visible = false; 
        app.PointDensityEditField_2Label.Visible = false;
    else
        % Show Radial-specific components
        app.RadialDensityEditField.Visible = true;
        app.RadialDensityEditFieldLabel.Visible = true;

        app.PointDensityEditField_2.Visible = true; 
        app.PointDensityEditField_2Label.Visible = true;

        % Hide Cartesian-specific components
        app.LineDensityEditField.Visible = false;
        app.LineDensityEditFieldLabel.Visible = false;

        app.PointDensityEditField.Visible = false;
        app.PointDensityEditFieldLabel.Visible = false;

        app.MatrixWidthEditField.Visible = false;
        app.MatrixWidthEditFieldLabel.Visible = false;

        app.SwapHorizontalVerticalLinesCheckBox.Visible = false;
    end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AcquisitionTrajectoryButtonGroup = uibuttongroup(app.LeftPanel);
            app.AcquisitionTrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionTrajectoryButtonGroupSelectionChanged, true);
            app.AcquisitionTrajectoryButtonGroup.Title = 'Acquisition Trajectory';
            app.CartesianButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.CartesianButton.Text = 'Cartesian';
            app.CartesianButton.Value = true;
            app.RadialButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.RadialButton.Text = 'Radial';
            app.PointDensityEditField_2Label = uilabel(app.LeftPanel);
            app.PointDensityEditField_2Label.Text = 'Point Density';
            app.LineDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.LineDensityEditFieldLabel.Text = 'Line Density';
            app.LineDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LineDensityEditField.Limits = [0 Inf];
            app.LineDensityEditField.ValueDisplayFormat = '%.0f';
            app.LineDensityEditField.Tag = 'cartesianLineDensity';
            app.LineDensityEditField.Placeholder = '100';
            app.PointDensityEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.RadialDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.RadialDensityEditFieldLabel.Text = 'Radial Density';
            app.PointDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.PointDensityEditFieldLabel.Text = 'Point Density';
            app.RadialDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointDensityEditField.Limits = [0 Inf];
            app.PointDensityEditField.ValueDisplayFormat = '%.0f';
            app.PointDensityEditField.Tag = 'cartesianPointDensity';
            app.PointDensityEditField.Placeholder = '100';
            app.MatrixWidthEditFieldLabel = uilabel(app.LeftPanel);
            app.MatrixWidthEditFieldLabel.Text = 'Matrix Width';
            app.MatrixWidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MatrixWidthEditField.Limits = [0 Inf];
            app.MatrixWidthEditField.ValueDisplayFormat = '%.0f';
            app.MatrixWidthEditField.Tag = 'cartesianMatrixWidth';
            app.MatrixWidthEditField.Placeholder = '100';
            app.SwapHorizontalVerticalLinesCheckBox = uicheckbox(app.LeftPanel);
            app.SwapHorizontalVerticalLinesCheckBox.Tag = 'cartesianSwapLines';
            app.SwapHorizontalVerticalLinesCheckBox.Text = 'Swap Horizontal / Vertical Lines';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Virtual_MRI_Scanner_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end